classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        StatesListBoxLabel    matlab.ui.control.Label
        StatesListBox         matlab.ui.control.ListBox
        CountryListBoxLabel   matlab.ui.control.Label
        CountryListBox        matlab.ui.control.ListBox
        optionsButtonGroup    matlab.ui.container.ButtonGroup
        cumulativeButton      matlab.ui.control.RadioButton
        dailyButton           matlab.ui.control.RadioButton
        dataButtonGroup       matlab.ui.container.ButtonGroup
        casesButton           matlab.ui.control.RadioButton
        deathsButton          matlab.ui.control.RadioButton
        bothButton            matlab.ui.control.RadioButton
        AveragingSliderLabel  matlab.ui.control.Label
        AveragingSlider       matlab.ui.control.Slider
    end

    
properties (Access = private)
        
        Averaging
        avrg
       
        avrg2

datavalu
datavalu2
      
     datacasevalu
    datadeathvalu
    datacumulativecasevalu
    datacumulativedeathvalu
globCaseValu
globDeathValu
globCumulativeCaseValu
globCumulativeDeathValu
value
valu
mydata
mystate
mycntry
    end
    
    methods (Access = private)


        function drawcase(app)
cla (app.UIAxes,'reset')
yyaxis(app.UIAxes,'left')
x=(1:length(app.avrg));
xticks(app.UIAxes,0:30:length(x))
xticklabels(app.UIAxes,{'01/01' '01/02' '01/03' '01/04' '01/05' '01/06' '01/07' '01/08' '01/09' '01/10' '01/11' '01/12' '01/01'})
ylim(app.UIAxes,'auto');
xlim(app.UIAxes,[1 length(x)]);
xlim(app.UIAxes,'manual');
xtickangle(app.UIAxes,45)
app.UIAxes.XGrid='on';
app.UIAxes.YGrid='on';
bar(app.UIAxes,x,app.avrg);
end

function drawdeath(app)
cla (app.UIAxes,'reset')
yyaxis(app.UIAxes,'right')
x=(1:length(app.avrg));
xticks(app.UIAxes,0:30:length(x))
xticklabels(app.UIAxes,{'01/01' '01/02' '01/03' '01/04' '01/05' '01/06' '01/07' '01/08' '01/09' '01/10' '01/11' '01/12' '01/01'})
xtickangle(app.UIAxes,45)
ylim(app.UIAxes,'auto');
xlim(app.UIAxes,[1 length(x)]);
xlim(app.UIAxes,'manual');
app.UIAxes.XGrid='on';
app.UIAxes.YGrid='on';
plot(app.UIAxes,x,app.avrg,"LineWidth",1,"Color",'r');
end
 

function drawboth(app)
cla (app.UIAxes,'reset')
yyaxis(app.UIAxes, 'left')
x=(1:length(app.avrg));
ylim(app.UIAxes,'auto');
xticks(app.UIAxes,0:30:length(x))
xticklabels(app.UIAxes,{'01/01' '01/02' '01/03' '01/04' '01/05' '01/06' '01/07' '01/08' '01/09' '01/10' '01/11' '01/12' '01/01'})
xtickangle(app.UIAxes,45)
xlim(app.UIAxes,[1 length(x)]);
xlim(app.UIAxes,'manual');
app.UIAxes.XGrid='on';
app.UIAxes.YGrid='on';
bar(app.UIAxes,x,app.avrg)
yyaxis(app.UIAxes, 'right')
ylim(app.UIAxes,'auto');
x=(1:length(app.avrg2));
xticks(app.UIAxes,0:30:length(x))
xticklabels(app.UIAxes,{'01/01' '01/02' '01/03' '01/04' '01/05' '01/06' '01/07' '01/08' '01/09' '01/10' '01/11' '01/12' '01/01'})
plot(app.UIAxes,x,app.avrg2,"LineWidth",1,"Color",'r');
end

function  myaverages(app)
      app.avrg=movmean(app.datavalu,app.Averaging);
     if app.bothButton.Value
     app.avrg2=movmean(app.datavalu2,app.Averaging);
     end
end


function myownglobaloption(app)
      if app.dailyButton.Value
            if app.casesButton.Value
               app.datavalu=app.globCaseValu;
               myaverages(app)
               drawcase(app);
               title(app.UIAxes,'number of cases globally')
            else
                if app.deathsButton.Value
                    app.datavalu=app.globDeathValu;
                    myaverages(app)
                    drawdeath(app)
                    title(app.UIAxes,'number of deaths globally')
                else
                    if  app.bothButton.Value
                        app.datavalu=app.globCaseValu;
                        app.datavalu2=app.globDeathValu;
                        myaverages(app)
                        drawboth(app)
                        title(app.UIAxes,'number of cases and deaths globally')
                    end
                end
            end
         else
          if app.cumulativeButton.Value
              if app.casesButton.Value
               app.datavalu=app.globCumulativeCaseValu;
               myaverages(app)
               drawcase(app);
               title(app.UIAxes,'cumulative number of cases globally')
            else
                if app.deathsButton.Value
                    app.datavalu=app.globCumulativeDeathValu;
                    myaverages(app)
                    drawdeath(app)
                    title(app.UIAxes,'cumulative number of deaths globally')
                else
                    if  app.bothButton.Value
                        app.datavalu=app.globCumulativeCaseValu;
                         app.datavalu2=app.globCumulativeDeathValu;
                         myaverages(app)
                         drawboth(app)
                         title(app.UIAxes,'cumulative number of cases and deaths globally')
                    end
                end
              end
          end
      end
end

function myownglobaldata(app)
if app.casesButton.Value
    if app.dailyButton.Value
        app.datavalu=app.globCaseValu;
        myaverages(app)
         drawcase(app);
             title(app.UIAxes,'number of cases globally')
    else
        if app.cumulativeButton.Value
            app.datavalu=app.globCumulativeCaseValu;
            myaverages(app)
               drawcase(app);
               title(app.UIAxes,'cumulative number of cases globally')

        end
    end
else
    if app.deathsButton.Value
       if app.dailyButton.Value
        app.datavalu=app.globDeathValu;
        myaverages(app)
               drawdeath(app);
               title(app.UIAxes,'number of deaths globally')
       else
           if app.cumulativeButton.Value
               app.datavalu=app.globCumulativeDeathValu;
               myaverages(app)
                    drawdeath(app)
                    title(app.UIAxes,'cumulative number of deaths globally')
           end
       end
    else
        if app.bothButton.Value
            if app.dailyButton.Value
            app.datavalu=app.globCaseValu;
            app.datavalu2=app.globDeathValu;
            myaverages(app)
               drawboth(app);
               title(app.UIAxes,'number of cases and deaths globally')
            else
                if app.cumulativeButton.Value
                    app.datavalu=app.globCumulativeCaseValu;
                    app.datavalu2=app.globCumulativeDeathValu;
                    myaverages(app)
                    drawboth(app);
                    title(app.UIAxes,'cumulative number of cases and deaths globally')
                end
            end
        end
    end
end
end


    function myowndata(app)
  if app.casesButton.Value
    if app.dailyButton.Value
        app.datavalu=app.datacasevalu;
        myaverages(app)
        drawcase(app);
        title(app.UIAxes,strcat('number of cases in ',app.mystate))

    else
        if app.cumulativeButton.Value
            app.datavalu=app.datacumulativecasevalu;
            myaverages(app)
               drawcase(app);
               title(app.UIAxes,strcat('cumulative number of cases in ',app.mystate))
        end
    end
else
    if app.deathsButton.Value
       if app.dailyButton.Value
        app.datavalu=app.datadeathvalu;
        myaverages(app)
               drawdeath(app);
               title(app.UIAxes,strcat('number of deaths in ',app.mystate))
       else
           if app.cumulativeButton.Value
               app.datavalu=app.datacumulativedeathvalu;
               myaverages(app)
                    drawdeath(app)
                    title(app.UIAxes,strcat('cumulative number of deaths in ',app.mystate))
           end
       end
    else
        if app.bothButton.Value
            if app.dailyButton.Value
            app.datavalu=app.datacasevalu;
            app.datavalu2=app.datadeathvalu;
            myaverages(app)
               drawboth(app);

               title(app.UIAxes,strcat('number of cases and deaths in ',app.mystate))
            else
                if app.cumulativeButton.Value
                    app.datavalu=app.datacumulativecasevalu;
                    app.datavalu2=app.datacumulativedeathvalu;
                    myaverages(app)
                    drawboth(app);
                    title(app.UIAxes,strcat('cumulative number of cases and deaths in ',app.mystate))
                end
            end
        end
    end
  end
    end


    function myownoption(app)
         if app.dailyButton.Value
            if app.casesButton.Value
               app.datavalu=app.datacasevalu;
               myaverages(app)
               drawcase(app);
               title(app.UIAxes,strcat('number of cases in ',app.mystate))
            else
                if app.deathsButton.Value
                    app.datavalu=app.datadeathvalu;
                    myaverages(app)
                    drawdeath(app)
                    title(app.UIAxes,strcat('number of deaths in ',app.mystate))
                else
                    if  app.bothButton.Value
                        app.datavalu=app.datacasevalu;
                        app.datavalu2=app.datadeathvalu;
                        myaverages(app)
                        drawboth(app)
                        title(app.UIAxes,strcat('number of cases and deaths in ',app.mystate))
                    end
                end
            end
         else
          if app.cumulativeButton.Value
              if app.casesButton.Value
               app.datavalu=app.datacumulativecasevalu;
               myaverages(app)
               drawcase(app);
               title(app.UIAxes,strcat('cumulative number of cases in ',app.mystate))
            else
                if app.deathsButton.Value
                    app.datavalu=app.datacumulativedeathvalu;
                    myaverages(app)
                    drawdeath(app)
                    title(app.UIAxes,strcat('cumulative number of deaths in ',app.mystate))
                else
                    if  app.bothButton.Value
                        app.datavalu=app.datacumulativecasevalu;
                         app.datavalu2=app.datacumulativedeathvalu;
                         myaverages(app)
                         drawboth(app)
                         title(app.UIAxes,strcat('cumulative number of cases and deaths in ',app.mystate))
                    end
                end
              end
          end
         end
    end
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         load qkrt-BxiRTeK7fgcYsU3vw_924002543e084e8fa745395261e942ed_covid_data.mat covid_data
            the=attch; 
            [~,cntry,~,glob1] =the.setdata(covid_data(2:end,1:end));

           
app.Averaging=app.AveragingSlider.Value;
app.value = app.CountryListBox.Value;
app.valu = app.StatesListBox.Value;

 app.globCaseValu=glob1.CaseValu;
 app.globDeathValu=glob1.DeathValu;
 app.globCumulativeCaseValu=glob1.CumulativeCaseValu;
 app.globCumulativeDeathValu=glob1.CumulativeDeathValu;


 if strcmp(app.value,'Global')
    app.StatesListBox.Items ={'All'};  
    myownglobaloption(app)
    myownglobaldata(app)
else
    for i=1:length(cntry)
        if strcmp(app.value,cntry{i}(1).CountryName)
            app.mycntry=cntry{i};
            app.StatesListBox.Items ={cntry{i}.StateName};
           
                    app.datacasevalu=app.mycntry(1).CaseValu;
                    app.datadeathvalu=app.mycntry(1).DeathValu;
                    app.datacumulativecasevalu=app.mycntry(1).CumulativeCaseValu;
                    app.datacumulativedeathvalu=app.mycntry(1).CumulativeDeathValu;
                    app.mystate=app.mycntry(1).CountryName;
                    myowndata(app)
                    myownoption(app)
        end
    end
end     
        end

        % Value changed function: StatesListBox
        function StatesListBoxValueChanged(app, event)
            app.valu = app.StatesListBox.Value;

 if strcmp(app.value,'Global')
    myownglobaloption(app)
    myownglobaldata(app)
 else
            for y=1:length(app.mycntry)
                if strcmp(app.valu,app.mycntry(y).StateName)         
                    app.datacasevalu=app.mycntry(y).CaseValu;
                    app.datadeathvalu=app.mycntry(y).DeathValu;
                    app.datacumulativecasevalu=app.mycntry(y).CumulativeCaseValu;
                    app.datacumulativedeathvalu=app.mycntry(y).CumulativeDeathValu;
                    if length(app.mycntry)==1
                        app.mystate=app.mycntry(1).CountryName;
                    else
                        if y>1
                            app.mystate=app.mycntry(y).StateName;
                        end
                    end
                    myowndata(app)
                    myownoption(app)
                    return;
                end
            end
 end
            
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            app.value = app.CountryListBox.Value;
 load qkrt-BxiRTeK7fgcYsU3vw_924002543e084e8fa745395261e942ed_covid_data.mat covid_data
 the=attch; 
[~ ,cntry ,~ ,~] =the.setdata(covid_data(2:end,1:end));
 
if strcmp(app.value,'Global')
    app.StatesListBox.Items ={'All'};
    myownglobaloption(app)
    myownglobaldata(app)
else
    for i=1:length(cntry)
        if strcmp(app.value,cntry{i}(1).CountryName)
            app.mycntry=cntry{i};
            app.StatesListBox.Items ={cntry{i}.StateName};
                    app.datacasevalu=app.mycntry(1).CaseValu;
                    app.datadeathvalu=app.mycntry(1).DeathValu;
                    app.datacumulativecasevalu=app.mycntry(1).CumulativeCaseValu;
                    app.datacumulativedeathvalu=app.mycntry(1).CumulativeDeathValu;
                    app.mystate=app.mycntry(1).CountryName;
                    myowndata(app)
                    myownoption(app)
        end
    end
end    
        end

        % Value changing function: AveragingSlider
        function AveragingSliderValueChanging(app, event)
            app.Averaging = event.Value;
if strcmp(app.value,'Global')
    myownglobaloption(app)
    myownglobaldata(app)
else
    myowndata(app)
    myownoption(app)
end
        end

        % Selection changed function: dataButtonGroup
        function dataButtonGroupSelectionChanged(app, event)
if strcmp(app.value,'Global')
  
    myownglobaldata(app)
else
    myowndata(app)
end

        end

        % Selection changed function: optionsButtonGroup
        function optionsButtonGroupSelectionChanged(app, event)
if strcmp(app.value,'Global')
    myownglobaloption(app)    
else
    myownoption(app)
end
  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.StatesListBoxLabel = uilabel(app.UIFigure);
            app.StatesListBoxLabel.Text = 'States';
            app.StatesListBox = uilistbox(app.UIFigure);
            app.StatesListBox.Items = {'All'};
            app.StatesListBox.ValueChangedFcn = createCallbackFcn(app, @StatesListBoxValueChanged, true);
            app.StatesListBox.Value = 'All';
            app.CountryListBoxLabel = uilabel(app.UIFigure);
            app.CountryListBoxLabel.Text = 'Country';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.Items = {'Global', 'Afghanistan', 'Albania', 'Algeria', 'Andorra', 'Angola', 'Antigua and Barbuda', 'Argentina', 'Armenia', 'Australia', 'Austria', 'Azerbaijan', 'Bahamas', 'Bahrain', 'Bangladesh', 'Barbados', 'Belarus', 'Belgium', 'Belize', 'Benin', 'Bhutan', 'Bolivia', 'Bosnia and Herzegovina', 'Botswana', 'Brazil', 'Brunei', 'Bulgaria', 'Burkina Faso', 'Burma', 'Burundi', 'Cabo Verde', 'Cambodia', 'Cameroon', 'Canada', 'Central African Republic', 'Chad', 'Chile', 'China', 'Colombia', 'Comoros', 'Congo (Brazzaville)Congo (Kinshasa)', 'Costa Rica', 'Cote d''Ivoire', 'Croatia', 'Cuba', 'Cyprus', 'Czechia', 'Denmark', 'Djibouti', 'Dominica', 'Dominican Republic', 'Ecuador', 'Egypt', 'El Salvador', 'Equatorial Guinea', 'Eritrea', 'Estonia', 'Eswatini', 'Ethiopia', 'Fiji', 'Finland', 'France', 'Gabon', 'Gambia', 'Georgia', 'Germany', 'Ghana', 'Greece', 'Grenada', 'Guatemala', 'Guinea', 'Guinea-Bissau', 'Guyana', 'Haiti', 'Holy See', 'Honduras', 'Hungary', 'Iceland', 'India', 'Indonesia', 'Iran', 'Iraq', 'Ireland', 'Israel', 'Italy', 'Jamaica', 'Japan', 'Jordan', 'Kazakhstan', 'Kenya', 'Korea', ' South', 'Kosovo', 'Kuwait', 'Kyrgyzstan', 'Laos', 'Latvia', 'Lebanon', 'Lesotho', 'Liberia', 'Libya', 'Liechtenstein', 'Lithuania', 'Luxembourg', 'MS Zaandam', 'Madagascar', 'Malawi', 'Malaysia', 'Maldives', 'Mali', 'Malta', 'Marshall Islands', 'Mauritania', 'Mauritius', 'Mexico', 'Micronesia', 'Moldova', 'Monaco', 'Mongolia', 'Montenegro', 'Morocco', 'Mozambique', 'Namibia', 'Nepal', 'Netherlands', 'New Zealand', 'Nicaragua', 'Niger', 'Nigeria', 'North Macedonia', 'Norway', 'Oman', 'Pakistan', 'Panama', 'Papua New Guinea', 'Paraguay', 'Peru', 'Philippines', 'Poland', 'Portugal', 'Qatar', 'Romania', 'Russia', 'Rwanda', 'Saint Kitts and Nevis', 'Saint Lucia', 'Saint Vincent and the Grenadines', 'Samoa', 'San Marino', 'Sao Tome and Principe', 'Saudi Arabia', 'Senegal', 'Serbia', 'Seychelles', 'Sierra Leone', 'Singapore', 'Slovakia', 'Slovenia', 'Solomon Islands', 'Somalia', 'South Africa', 'South Sudan', 'Spain', 'Sri Lanka', 'Sudan', 'Suriname', 'Sweden', 'Switzerland', 'Syria', 'Taiwan*', 'Tajikistan', 'Tanzania', 'Thailand', 'Timor-Leste', 'Togo', 'Trinidad and Tobago', 'Tunisia', 'Turkey', 'Uganda', 'Ukraine', 'United Arab Emirates', 'United Kingdom', 'United States', 'Uruguay', 'Uzbekistan', 'Vanuatu', 'Venezuela', 'Vietnam', 'West Bank and Gaza', 'Yemen', 'Zambia', 'Zimbabwe'};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.CountryListBox.Value = 'Global';
            app.optionsButtonGroup = uibuttongroup(app.UIFigure);
            app.optionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @optionsButtonGroupSelectionChanged, true);
            app.optionsButtonGroup.Title = 'options';
            app.cumulativeButton = uiradiobutton(app.optionsButtonGroup);
            app.cumulativeButton.Text = 'cumulative';
            app.cumulativeButton.Value = true;
            app.dailyButton = uiradiobutton(app.optionsButtonGroup);
            app.dailyButton.Text = 'daily';
            app.dataButtonGroup = uibuttongroup(app.UIFigure);
            app.dataButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @dataButtonGroupSelectionChanged, true);
            app.dataButtonGroup.Title = 'data';
            app.casesButton = uiradiobutton(app.dataButtonGroup);
            app.casesButton.Text = 'cases';
            app.casesButton.Value = true;
            app.deathsButton = uiradiobutton(app.dataButtonGroup);
            app.deathsButton.Text = 'deaths';
            app.bothButton = uiradiobutton(app.dataButtonGroup);
            app.bothButton.Text = 'both';
            app.AveragingSliderLabel = uilabel(app.UIFigure);
            app.AveragingSliderLabel.Text = 'Averaging';
            app.AveragingSlider = uislider(app.UIFigure);
            app.AveragingSlider.Limits = [1 15];
            app.AveragingSlider.ValueChangingFcn = createCallbackFcn(app, @AveragingSliderValueChanging, true);
            app.AveragingSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end